classdef Semana5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        ValvulaSlider       matlab.ui.control.Slider
        ValvulaSliderLabel  matlab.ui.control.Label
        ButtonGroup         matlab.ui.container.ButtonGroup
        TorricelliButton    matlab.ui.control.RadioButton
        MariotteButton      matlab.ui.control.RadioButton
        PopoteSlider        matlab.ui.control.Slider
        PopoteSliderLabel   matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        popoteH double
        valvulaD double    % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function Torricelli(app, event)
            %Cambiar y acceder a los datos conforme la Interfaz
            selectedButton = app.ButtonGroup.SelectedObject;

            title = selectedButton.Text;
            app.UIAxes.Title.String = title;    

            %----------------------
            %Botella
            
            posBx=4;
            posBy=2;
            
            Bbase = 7.62;
            Baltura = 16;
            botellaX = [posBx, posBx, posBx+Bbase,posBx+Bbase];
            botellaY = [posBy + Baltura, posBy,posBy, posBy + Baltura];
            
            grafica = plot(app.UIAxes ,botellaX, botellaY);
            set(grafica,"linewidth",3,"color","black")
            %axis([-1,10,-1,18])
            
            aguaH = zeros(1,100);
            aguaH(1)=12;
            baseArea = pi*(Bbase/2)^2;
            aguaVol = baseArea*aguaH(1);
            
            agua = rectangle(app.UIAxes ,"position",[posBx,posBy,Bbase,aguaH(1)]);
            set(agua,"facecolor","cyan")

            %---------------------------
            %Valvula
            valvulaLong=0.8;
            posValH=3.5;
            valvula = rectangle(app.UIAxes ,"position",[posBx+Bbase,posBy+posValH,valvulaLong,1]);
            set(valvula,"facecolor","black");

             %Ciencia
             t = zeros(1,100);
             ti=0; t(1)=0;
             tf=30;
             N=80;
             dt=(tf-ti)/N;
             n = 2;


            if(selectedButton.Text == "Torricelli")
                app.PopoteSlider.Visible = 'off';


                while aguaH(n-1)-0.2 > posValH
                        delete(agua)
                        t(n)=t(n-1)+dt;
                        dh=aguaH(n-1)-posValH;
                        v_toricelli = sqrt(2*9.81*dh);
                        G = v_toricelli*dt*app.valvulaD;
                        aguaVol=aguaVol-G;
                        aguaH(n) = aguaVol/baseArea;
                        agua = rectangle(app.UIAxes ,"position",[posBx,posBy,Bbase,aguaH(n)]);
                        set(agua,"facecolor","#55abdd");
                        pause(0.001);
                        n = n+1;
                end
                
                figure(1);
                hold on;
                x = t./2;
                y = aguaH;
                plot(x,y, 'Color', '#003399', 'LineWidth',1.5);
                y = 100*((0.00012*x.^2)-(0.0062.*x)+0.12);
                plot(x,y,"Color","#ec0000", 'LineWidth',1.5);
                legend("Matlab","Tracker");
                %title('Gráfica de posición: Torricelli'); xlabel('Tiempo'); ylabel('Altura');


            else

                %Mariotte
                app.PopoteSlider.Visible = 'on';
                %--------------------------
                %popote
                tapaAltura = 2.54;
                
                popote = rectangle(app.UIAxes,"position", [posBx+Bbase/2,posBy+posValH+app.popoteH+app.valvulaD,0.75,Baltura+tapaAltura+2]);
                set(popote,"facecolor","red")
                disp(posBy+posValH+app.popoteH+app.valvulaD)
                %------------------------
                %tapa

                tapa = rectangle(app.UIAxes,"position", [posBx,posBy+Baltura-tapaAltura,Bbase,tapaAltura]);
                set(tapa,"facecolor","black")
            
                while aguaH(n-1)-0.2 > posValH
                        delete(agua)
                        if(aguaH(n-1)>posValH+app.popoteH); dh=app.popoteH-app.valvulaD;else; dh=aguaH(n-1)-posValH; end
                        t(n)=t(n-1)+dt;
                        v_toricelli = sqrt(2*9.81*dh);
                        G = v_toricelli*dt*app.valvulaD;
                        aguaVol=aguaVol-G;
                        aguaH(n) = aguaVol/baseArea;
                        agua = rectangle(app.UIAxes ,"position",[posBx,posBy,Bbase,aguaH(n)]);
                        set(agua,"facecolor","#55abdd");
                        pause(0.001);
                        n = n+1;
                end

                figure(2);
                hold on;
                x = t./3.2;
                y = aguaH;
                plot(x,y, 'Color', '#003399', 'LineWidth',1.5);
                y = 100*((-0.0009.*x)+0.12);
                plot(x,y,"Color","#ec0000", 'LineWidth',1.5);
                %title('Gráfica de posición: Mariotte'); xlabel('Tiempo'); ylabel('Altura');
                legend("Matlab","Tracker");

            end
            
        end

        % Value changed function: PopoteSlider
        function PopoteSliderValueChanged(app, event)
              app.popoteH = app.PopoteSlider.Value;
              disp(app.popoteH);
        end

        % Button down function: ButtonGroup
        function ButtonGroupButtonDown(app, event)

            
        end

        % Value changed function: ValvulaSlider
        function ValvulaSliderValueChanged(app, event)
           app.valvulaD = app.ValvulaSlider.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {207, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PopoteSliderLabel = uilabel(app.LeftPanel);
            app.PopoteSliderLabel.Text = 'Popote';
            app.PopoteSlider = uislider(app.LeftPanel);
            app.PopoteSlider.Limits = [1 6];
            app.PopoteSlider.MajorTicks = [1 2 3 4 5 6];
            app.PopoteSlider.ValueChangedFcn = createCallbackFcn(app, @PopoteSliderValueChanged, true);
            app.PopoteSlider.MinorTicks = [];
            app.PopoteSlider.Value = 1;
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Torricelli, true);
            app.ButtonGroup.Title = 'Button Group';
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.MariotteButton = uiradiobutton(app.ButtonGroup);
            app.MariotteButton.Text = 'Mariotte';
            app.MariotteButton.Value = true;
            app.TorricelliButton = uiradiobutton(app.ButtonGroup);
            app.TorricelliButton.Text = 'Torricelli';
            app.ValvulaSliderLabel = uilabel(app.LeftPanel);
            app.ValvulaSliderLabel.Text = 'Valvula';
            app.ValvulaSlider = uislider(app.LeftPanel);
            app.ValvulaSlider.Limits = [0 6];
            app.ValvulaSlider.ValueChangedFcn = createCallbackFcn(app, @ValvulaSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Teorema de Torricelli/Mariotte')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Altura')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 20];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Semana5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end