classdef Semana5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        ValvulaSlider       matlab.ui.control.Slider
        ValvulaSliderLabel  matlab.ui.control.Label
        ButtonGroup         matlab.ui.container.ButtonGroup
        TorricelliButton    matlab.ui.control.RadioButton
        MariotteButton      matlab.ui.control.RadioButton
        PopoteSlider        matlab.ui.control.Slider
        PopoteSliderLabel   matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function Torricelli(app, event)
            
            %Cambiar y acceder a los datos conforme la Interfaz
            selectedButton = app.ButtonGroup.SelectedObject;

            title = selectedButton.Text;
            app.UIAxes.Title.String = title;

            popoteH = app.PopoteSlider.Value;
            valvulaD = app.ValvulaSlider.Value; 
            disp(valvulaD);

            %-----------------------
            %Botella
            
            posBx=4;
            posBy=2;
            
            Bbase = 7.62;
            Baltura = 16;
            botellaX = [posBx, posBx, posBx+Bbase,posBx+Bbase];
            botellaY = [posBy + Baltura, posBy,posBy, posBy + Baltura];
            
            grafica = plot(app.UIAxes ,botellaX, botellaY);
            set(grafica,"linewidth",3,"color","black")
            %axis([-1,10,-1,18])
            
            aguaH=12;
            baseArea = pi*(Bbase/2)^2;
            aguaVol = baseArea*aguaH;
            
            agua = rectangle(app.UIAxes ,"position",[posBx,posBy,Bbase,aguaH]);
            set(agua,"facecolor","cyan")

            %---------------------------
            %Valvula
            valvulaDiam=1;
            valvulaLong=valvulaD;
            posValH=3.5;
            valvula = rectangle(app.UIAxes ,"position",[posBx+Bbase-1,posBy+posValH ,valvulaLong+1,valvulaDiam]);
            set(valvula,"facecolor","black");


            if(selectedButton.Text == "Torricelli")
                %Torricelli
                t = zeros(1,100);
                aguaHVect = zeros(1,100);
                ti=0; t(1)=0;
                tf=20;
                N=80;
                dt=(tf-ti)/N;
                n = 0;
            
                while aguaH-0.2 > posValH
                        delete(agua)
                        t(n+1)=ti+dt;
                        v_toricelli = sqrt(2*9.81*aguaH);
                        G = v_toricelli*dt*valvulaLong;
                        aguaVol =aguaVol-G;
                        aguaH = aguaVol/baseArea;
                        aguaHVect(n+1) = aguaH;
                        agua = rectangle(app.UIAxes ,"position",[posBx,posBy,Bbase,aguaH]);
                        set(agua,"facecolor","#55abdd");
                        pause(0.04);
                        n = n+1;
                end
                %Grafica Respecto Altura respecto a tiempo
                figure(2);
                disp(aguaHVect);
                hold on;
    
            else

                %Mariotte

                %--------------------------
                %tapita
                tapaAltura = 3.62;
                
                tapa = rectangle(app.UIAxes,"position", [posBx,posBy+Baltura,Bbase,tapaAltura]);
                set(tapa,"facecolor","black")

                %--------------------------
                %popote
            
                popote = rectangle(app.UIAxes,"position", [posBx+Bbase/2,posBy+popoteH,valvulaDiam,Baltura+tapaAltura+2]);
                set(popote,"facecolor","red")
                

                %Ciencia
                t = zeros(1,100);
                aguaHVect = zeros(1,100);
                ti=0; t(1)=0;
                tf=20;
                N=80;
                dt=(tf-ti)/N;
                n = 0;
            
                while aguaH-0.2 > posValH
                        delete(agua)
                        t(n+1)=ti+dt;
                        v_mariotte = 9.81;
                        G = v_mariotte*dt*valvulaLong;
                        aguaVol =aguaVol-G;
                        aguaH = aguaVol/baseArea;
                        aguaHVect(n+1) = aguaH;
                        agua = rectangle(app.UIAxes ,"position",[posBx,posBy,Bbase,aguaH]);
                        set(agua,"facecolor","#55abdd");
                        pause(0.04);
                        n = n+1;
                end
                %Grafica Respecto Altura respecto a tiempo
                figure(2);
                disp(aguaHVect);
                hold on;
                
            end
            
            %Tracker
            tTracker = 0:24;
            A = 1.573E-4;
            B = -7.668E-3;
            C = 1.404E-1;
            y = 100.*(A.*tTracker.^2 + B.*tTracker + C);
            plot(tTracker, y);


        end

        % Value changed function: PopoteSlider
        function PopoteSliderValueChanged(app, event)

            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PopoteSliderLabel = uilabel(app.LeftPanel);
            app.PopoteSliderLabel.Text = 'Popote';
            app.PopoteSlider = uislider(app.LeftPanel);
            app.PopoteSlider.Limits = [0 12];
            app.PopoteSlider.ValueChangedFcn = createCallbackFcn(app, @PopoteSliderValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Torricelli, true);
            app.ButtonGroup.Title = 'Button Group';
            app.MariotteButton = uiradiobutton(app.ButtonGroup);
            app.MariotteButton.Text = 'Mariotte';
            app.MariotteButton.Value = true;
            app.TorricelliButton = uiradiobutton(app.ButtonGroup);
            app.TorricelliButton.Text = 'Torricelli';
            app.ValvulaSliderLabel = uilabel(app.LeftPanel);
            app.ValvulaSliderLabel.Text = 'Valvula';
            app.ValvulaSlider = uislider(app.LeftPanel);
            app.ValvulaSlider.Limits = [0 6];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Teorema de Torricelli/Mariotte')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Altura')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 20];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Semana5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end