classdef dielectroforesis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlacasButtonGroup            matlab.ui.container.ButtonGroup
        FinitasButton                matlab.ui.control.RadioButton
        InfinitasButton              matlab.ui.control.RadioButton
        DistanciaSlider              matlab.ui.control.Slider
        DistanciaSliderLabel         matlab.ui.control.Label
        LongitudNegativaSlider       matlab.ui.control.Slider
        LongitudNegativaSliderLabel  matlab.ui.control.Label
        LongitudPositivaSlider       matlab.ui.control.Slider
        LongitudPositivaSliderLabel  matlab.ui.control.Label
        CargaTotalSlider             matlab.ui.control.Slider
        CargaTotalLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        cargaTotal double = 200;
        longPositiva double = 50;
        longNegativa double = 30;
        distancia double = 10;
        axisEnabled logical = false;

    end
    
    methods (Access = private)
        
        function results = calcularCampoElectricoFinito( app, cargaTotal, longPositiva, longNegativa, distancia)
            %Definición de variables
            
            cargaPositiva = (cargaTotal / 100) * 10^-12;
            cargaNegativa = -1 * cargaPositiva;
            
            numCargas = 100;
            
            cargaPositivaY = linspace(-(longPositiva / 2), longPositiva / 2, numCargas);
            cargaNegativaY = linspace(-(longNegativa / 2), longNegativa / 2, numCargas);
            
            cargaPositivaX = (0 - (distancia / 2));
            cargaNegativaX = (0 + (distancia / 2));
            
            K = 8.99 * 10^9;
            r = min(min(distancia, longPositiva), longNegativa) / 7;
            
            dominio = linspace(cargaPositivaX - distancia / 2 , cargaNegativaX + distancia / 2, 30);
            
            if cargaPositivaY(end) > cargaNegativaY(end)
                rango = linspace(cargaPositivaY(1) - distancia / 2, cargaPositivaY(end) + distancia / 2, 30);
            else
                rango = linspace(cargaNegativaY(1) - distancia / 2, cargaNegativaY(end) + distancia / 2, 30);
            end
            
            [x, y] = meshgrid(dominio, rango);
            
            Ex = x;
            Ey = y;
            
            for i = 1:length(x)
                for j = 1:length(y)
                    Ex(i, j) = 0;
                    Ey(i, j) = 0;
                    for n = 1:numCargas
                        Ex(i, j) = Ex(i, j) + K * (( cargaPositiva * (x(i, j) - cargaPositivaX) / ((x(i, j) - cargaPositivaX)^2 + (y(i, j) - cargaPositivaY(n))^2 )^(3 / 2) ) + ( cargaNegativa * (x(i, j) - cargaNegativaX) / ((x(i, j) - cargaNegativaX)^2 + (y(i, j) - cargaNegativaY(n))^2 )^(3 / 2) ));
                        Ey(i, j) = Ey(i, j) + K * (( cargaPositiva * (y(i, j) - cargaPositivaY(n)) / ((y(i, j) - cargaPositivaY(n))^2 + (x(i, j) - cargaPositivaX)^2 )^(3 / 2) ) + ( cargaNegativa * (y(i, j) - cargaNegativaY(n)) / ((y(i, j) - cargaNegativaY(n))^2 + (x(i, j) - cargaNegativaX)^2 )^(3 / 2) ));
                    end
                end
            end
            
            E = sqrt(Ex.^2 + Ey.^2);
            quiver(app.UIAxes, x(1:2:end,1:2:end), y(1:2:end,1:2:end), Ex(1:2:end,1:2:end)./(E(1:2:end,1:2:end).*3), Ey(1:2:end,1:2:end)./E(1:2:end,1:2:end), "LineWidth", 1.8, "MaxHeadSize", 10);
            
            for n = 1:numCargas
                rectangle(app.UIAxes, "Position", [cargaPositivaX-r/2, cargaPositivaY(n)-r/2, r, r], "Curvature", [1 1], "FaceColor", "#15eb35", "EdgeColor", "#15eb35");
                rectangle(app.UIAxes, "Position", [cargaNegativaX-r/2, cargaNegativaY(n)-r/2, r, r], "Curvature", [1 1], "FaceColor", "#3656ff", "EdgeColor", "#3656ff");
            end

            legend(app.UIAxes, 'Campo Eléctrico');

            axis(app.UIAxes, [dominio(1), dominio(end), rango(1), rango(end)]);

            results = "";
        end

        function results = calcularCampoElectricoInfinito(app)
            cargaTotalInf = 200; %input("Introduce la carga Total: ");

            cargaPositiva = (cargaTotalInf / 100) * 10^-12;
            cargaNegativa = -1 * cargaPositiva;
            longPositivaInf = 72; %input("Longitud barra positiva: ");
            longNegativaInf = 72; %input("Longitud barra negativa: ");
            
            numCargas = 100;
            
            cargaPositivaY = linspace(-(longPositivaInf / 2), longPositivaInf / 2, numCargas);
            cargaNegativaY = linspace(-(longNegativaInf / 2), longNegativaInf / 2, numCargas);
            
            distanciaInf = 10; %input("distancia entre las varillas: ");
            
            cargaPositivaX = (0 - (distanciaInf / 2));
            cargaNegativaX = (0 + (distanciaInf / 2));
            
            K = 8.99 * 10^9;

            rInf = 1.4286;
            
            dominio = linspace(cargaPositivaX - distanciaInf / 2 , cargaNegativaX + distanciaInf / 2, 30);
            
            if cargaPositivaY(end) > cargaNegativaY(end)
                rango = linspace(cargaPositivaY(1) - distanciaInf / 2, cargaPositivaY(end) + distanciaInf / 2, 30);
            else
                rango = linspace(cargaNegativaY(1) - distanciaInf / 2, cargaNegativaY(end) + distanciaInf / 2, 30);
            end
            
            [x, y] = meshgrid(dominio, rango);
            
            Ex = x;
            Ey = y;
            
            for i = 1:length(x)
                for j = 1:length(y)
                    Ex(i, j) = 0;
                    Ey(i, j) = 0;
                    for n = 1:numCargas
                        Ex(i, j) = Ex(i, j) + K * (( cargaPositiva * (x(i, j) - cargaPositivaX) / ((x(i, j) - cargaPositivaX)^2 + (y(i, j) - cargaPositivaY(n))^2 )^(3 / 2) ) + ( cargaNegativa * (x(i, j) - cargaNegativaX) / ((x(i, j) - cargaNegativaX)^2 + (y(i, j) - cargaNegativaY(n))^2 )^(3 / 2) ));
                        Ey(i, j) = Ey(i, j) + K * (( cargaPositiva * (y(i, j) - cargaPositivaY(n)) / ((y(i, j) - cargaPositivaY(n))^2 + (x(i, j) - cargaPositivaX)^2 )^(3 / 2) ) + ( cargaNegativa * (y(i, j) - cargaNegativaY(n)) / ((y(i, j) - cargaNegativaY(n))^2 + (x(i, j) - cargaNegativaX)^2 )^(3 / 2) ));
                    end
                end
            end
            
            E = sqrt(Ex.^2 + Ey.^2);
            quiver(app.UIAxes, x(1:2:end,1:2:end), y(1:2:end,1:2:end), Ex(1:2:end,1:2:end)./(E(1:2:end,1:2:end).*6), Ey(1:2:end,1:2:end)./E(1:2:end,1:2:end), "LineWidth", 1.8, "MaxHeadSize", 10);
            
            for n = 1:numCargas
                rectangle(app.UIAxes, "Position", [cargaPositivaX-rInf/2, cargaPositivaY(n)-rInf/2, rInf, rInf], "Curvature", [1 1], "FaceColor", "#15eb35", "EdgeColor", "#15eb35");
                rectangle(app.UIAxes, "Position", [cargaNegativaX-rInf/2, cargaNegativaY(n)-rInf/2, rInf, rInf], "Curvature", [1 1], "FaceColor", "#3656ff", "EdgeColor", "#3656ff");
            end
            
            legend(app.UIAxes, 'Campo Eléctrico');

            axis(app.UIAxes, [-10 10 -30 30]);

            results = "";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.calcularCampoElectricoInfinito();

        end

        % Value changing function: CargaTotalSlider
        function CargaTotalSliderValueChanging(app, event)
            app.cargaTotal = event.Value;
            cla(app.UIAxes);

            app.calcularCampoElectricoFinito(app.cargaTotal, app.longPositiva, app.longNegativa, app.distancia);
            

        end

        % Value changing function: LongitudPositivaSlider
        function LongitudPositivaSliderValueChanging(app, event)
            app.longPositiva = event.Value;
            cla(app.UIAxes);

            app.calcularCampoElectricoFinito(app.cargaTotal, app.longPositiva, app.longNegativa, app.distancia);
            
        end

        % Value changing function: LongitudNegativaSlider
        function LongitudNegativaSliderValueChanging(app, event)
            app.longNegativa = event.Value;
            cla(app.UIAxes);

            app.calcularCampoElectricoFinito(app.cargaTotal, app.longPositiva, app.longNegativa, app.distancia);
            
        end

        % Value changing function: DistanciaSlider
        function DistanciaSliderValueChanging(app, event)
            app.distancia = event.Value;
            cla(app.UIAxes);

            app.calcularCampoElectricoFinito(app.cargaTotal, app.longPositiva, app.longNegativa, app.distancia);

            
        end

        % Selection changed function: PlacasButtonGroup
        function PlacasButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlacasButtonGroup.SelectedObject;
            cla(app.UIAxes);
            
            if selectedButton.Text == "Finitas"
                app.calcularCampoElectricoFinito(app.cargaTotal, app.longPositiva, app.longNegativa, app.distancia);

                app.DistanciaSlider.Visible = "on";
                app.DistanciaSliderLabel.Visible = "on";
                app.LongitudNegativaSliderLabel.Visible = "on";
                app.LongitudNegativaSlider.Visible = "on";
                app.LongitudPositivaSliderLabel.Visible = "on";
                app.LongitudPositivaSlider.Visible = "on";
                app.CargaTotalLabel.Visible = "on";
                app.CargaTotalSlider.Visible = "on";
            else
                app.calcularCampoElectricoInfinito();

                app.DistanciaSlider.Visible = "off";
                app.DistanciaSliderLabel.Visible = "off";
                app.LongitudNegativaSliderLabel.Visible = "off";
                app.LongitudNegativaSlider.Visible = "off";
                app.LongitudPositivaSliderLabel.Visible = "off";
                app.LongitudPositivaSlider.Visible = "off";
                app.CargaTotalLabel.Visible = "off";
                app.CargaTotalSlider.Visible = "off";
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dielectroforesis')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 15];
            app.UIAxes.YLim = [0 14];
            app.UIAxes.XTick = [0 2.5 5 7.5 10 12.5 15];
            app.CargaTotalLabel = uilabel(app.UIFigure);
            app.CargaTotalLabel.Visible = 'off';
            app.CargaTotalLabel.Text = {'Carga'; 'Total'};
            app.CargaTotalSlider = uislider(app.UIFigure);
            app.CargaTotalSlider.Limits = [100 200];
            app.CargaTotalSlider.ValueChangingFcn = createCallbackFcn(app, @CargaTotalSliderValueChanging, true);
            app.CargaTotalSlider.Visible = 'off';
            app.CargaTotalSlider.Value = 200;
            app.LongitudPositivaSliderLabel = uilabel(app.UIFigure);
            app.LongitudPositivaSliderLabel.Visible = 'off';
            app.LongitudPositivaSliderLabel.Text = {'Longitud'; 'Positiva'};
            app.LongitudPositivaSlider = uislider(app.UIFigure);
            app.LongitudPositivaSlider.Limits = [30 100];
            app.LongitudPositivaSlider.ValueChangingFcn = createCallbackFcn(app, @LongitudPositivaSliderValueChanging, true);
            app.LongitudPositivaSlider.Visible = 'off';
            app.LongitudPositivaSlider.Value = 50;
            app.LongitudNegativaSliderLabel = uilabel(app.UIFigure);
            app.LongitudNegativaSliderLabel.Visible = 'off';
            app.LongitudNegativaSliderLabel.Text = {'Longitud'; 'Negativa'};
            app.LongitudNegativaSlider = uislider(app.UIFigure);
            app.LongitudNegativaSlider.Limits = [30 100];
            app.LongitudNegativaSlider.ValueChangingFcn = createCallbackFcn(app, @LongitudNegativaSliderValueChanging, true);
            app.LongitudNegativaSlider.Visible = 'off';
            app.LongitudNegativaSlider.Value = 30;
            app.DistanciaSliderLabel = uilabel(app.UIFigure);
            app.DistanciaSliderLabel.Visible = 'off';
            app.DistanciaSliderLabel.Text = 'Distancia';
            app.DistanciaSlider = uislider(app.UIFigure);
            app.DistanciaSlider.Limits = [5 25];
            app.DistanciaSlider.ValueChangingFcn = createCallbackFcn(app, @DistanciaSliderValueChanging, true);
            app.DistanciaSlider.Visible = 'off';
            app.DistanciaSlider.Value = 10;
            app.PlacasButtonGroup = uibuttongroup(app.UIFigure);
            app.PlacasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlacasButtonGroupSelectionChanged, true);
            app.PlacasButtonGroup.Title = 'Placas';
            app.InfinitasButton = uiradiobutton(app.PlacasButtonGroup);
            app.InfinitasButton.Text = 'Infinitas';
            app.InfinitasButton.Value = true;
            app.FinitasButton = uiradiobutton(app.PlacasButtonGroup);
            app.FinitasButton.Text = 'Finitas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dielectroforesis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end